classdef MNIST < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure                   % UI Figure
        load                  matlab.ui.control.Button           % 加载
        identify              matlab.ui.control.Button           % 识别
        LabelTextArea         matlab.ui.control.Label            % 输出
        output                matlab.ui.control.TextArea        
        exit                  matlab.ui.control.Button           % 退出
        LabelDropDown         matlab.ui.control.Label            % 模式选择
        mode                  matlab.ui.control.DropDown         % 随机, 手写
        LabelNumericEditField matlab.ui.control.Label            % 结果
        result                matlab.ui.control.NumericEditField % [-Inf Inf]
        Label                 matlab.ui.control.Label            % 手写数字识别
        UIAxes                matlab.ui.control.UIAxes           % 图像
    end


    properties (Access = private)
        mode_value  % Description
        target 
        result_value
        output_value
        Weights
        Biases
        Image
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Weights = app.load('Result.mat','Weights');%加载训练好的模型参数
            app.Biases = app.load('Result.mat','Biases');
            app.Image = app.load('Train.mat');%加载MNIST数据集
        end

        % mode value changed function
        function modeValueChanged(app, event)
            value = app.mode.Value;
            if strcmp(value,'随机')
                app.mode_value = 1;
            else if strcmp(value,'手写')
                    app.mode_value = 0;
                end
            end
          
                
        end

        % image_panel size change function
        function PanelSizeChanged(app)
            position = app.image_panel.Position;
            
        end

        % load button pushed function
        function loadButtonPushed(app)
            if app.mode_value
                [~,~,M] = size(app.Image);
                n = randi([1,M],1,1);
                app.target = app.Image(:,:,n);
                image(app.UIAxes,app.target);
            end
        end

        % exit button pushed function
        function exitButtonPushed(app)
            delete(app.UIFigure)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.load = uibutton(app.UIFigure, 'push');
            app.load.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed);
            app.load.Text = '加载';
            app.identify = uibutton(app.UIFigure, 'push');
            app.identify.Text = '识别';
            app.LabelTextArea = uilabel(app.UIFigure);
            app.LabelTextArea.Text = '输出';
            app.output = uitextarea(app.UIFigure);
            app.exit = uibutton(app.UIFigure, 'push');
            app.exit.ButtonPushedFcn = createCallbackFcn(app, @exitButtonPushed);
            app.exit.Text = '退出';
            app.LabelDropDown = uilabel(app.UIFigure);
            app.LabelDropDown.Text = '模式选择';
            app.mode = uidropdown(app.UIFigure);
            app.mode.Items = {'随机', '手写'};
            app.mode.ValueChangedFcn = createCallbackFcn(app, @modeValueChanged, true);
            app.mode.Value = '随机';
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = '结果';
            app.result = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '手写数字识别';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '图像');
        end
    end

    methods (Access = public)

        % Construct app
        function app = MNIST()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end